classdef hlavniOkno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LabelPath                    matlab.ui.control.Label
        AUTOSCALEButton              matlab.ui.control.Button
        dokonceno                    matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        ULOZITButton                 matlab.ui.control.Button
        umintenzitaEditField         matlab.ui.control.EditField
        umintenzitaEditFieldLabel    matlab.ui.control.Label
        umfrekvencedoEditField       matlab.ui.control.EditField
        umfrekvencedoEditFieldLabel  matlab.ui.control.Label
        umfrekvenceodEditField       matlab.ui.control.EditField
        umfrekvenceodEditFieldLabel  matlab.ui.control.Label
        frekvencemaskovachotnuEditField  matlab.ui.control.EditField
        frekvencemaskovachotnuEditFieldLabel  matlab.ui.control.Label
        intenzitatestovanhotnuEditField  matlab.ui.control.EditField
        intenzitatestovanhotnuEditFieldLabel  matlab.ui.control.Label
        vektortestovacchfrekvencEditField  matlab.ui.control.EditField
        vektortestovacchfrekvencEditFieldLabel  matlab.ui.control.Label
        SlysenoButton                matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        mask                         matlab.ui.control.NumericEditField
        masknetestucha1ANO0NEEditFieldLabel  matlab.ui.control.Label
        zvysovani                    matlab.ui.control.NumericEditField
        zvysovaniintenzity1schod0spojEditFieldLabel  matlab.ui.control.Label
        NVODButton                   matlab.ui.control.Button
        nastavenhodnotyLabel         matlab.ui.control.Label
        NASTAVENButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    %% věci co bych mohl dodělat kdyby zbyl čas

    % nastavení kroků zvyšování intenzity např z 10 na 5 dB

    % lepší ukládání výsledku ne jen screenshot

    % vymyslet jaký používat typ filtru případně doplnit teorii o
    % filtrech do práce

    %filtrovat digitálně ně pře butter


    properties (Access = private)

        nastaveni
        navod

        %% nastavení "Globálních" proměnných
        freqVect = [125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000]; % defaultní nastavení testovacích frekvencí
        startMeasFlag = 0; % flag pro signalizaci začátku měření
        dontSaveTime = 0; % flag jestli se má ukláddt čas čekání na registraci testovacího tónu
        timeLeft = []; % vektor na ukládání času čekání na reakci levého ucha
        timeRight = []; % vektor na ukládání času čekání na reakci pravého ucha
        measCounter = 1; % počítadlo proběhlých měření
        leftMeas = 1; % flag pro úrčení měření pravého/levého ucha
        path = "C:\Users\pache\Desktop\bakalarka\screenyZapky\"; % defaultní adresář ukládání screenů

        %% proměnné nastavitelné v nastavení
        frekvencemaskovacihotonu = [];
        intenzitatestovacihotonu = [];
        sumfrekvenceod = [];
        sumfrekvencedo = [];
        sumintenzita = [];

        dokoncenoProcenta = 0; % procenta progresu měření
        ScaleFlag = 0; % Flag ktrý oddaluje a přibližuje výsledný graf

        steps = [0,10, 20, 30, 40, 50, 60, 70, 80, 90]; % kroky zvyšování intenzity schodovitého zvyšování intenzity v dB


    end

    methods (Access = public)
        %hlaní funkce pro přeposílání dat mezi main a nastavením
        %získává data z okna nastavení a některé data ve formátu string
        %konvertuje na vektor

        function importNastaveni(app,vektorFrekvenci,MaskToneFreqV,MaskToneIntV,MaskLowFreqV,MaskHighFreqV,IntSumV,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu)

            app.mask.Value=MaskovaniNetestovanehoUcha;
            app.zvysovani.Value=ZvysovaniIntenzityTonu;

            app.vektortestovacchfrekvencEditField.Value= vektorFrekvenci;
            app.freqVect = str2num(vektorFrekvenci);
            app.frekvencemaskovachotnuEditField.Value = MaskToneFreqV;
            app.frekvencemaskovacihotonu = str2num(MaskToneFreqV);
            app.intenzitatestovanhotnuEditField.Value = MaskToneIntV;
            app.intenzitatestovacihotonu = str2num(MaskToneIntV);
            app.umfrekvenceodEditField.Value = MaskLowFreqV;
            app.sumfrekvenceod = str2num(MaskLowFreqV);
            app.umfrekvencedoEditField.Value = MaskHighFreqV;
            app.sumfrekvencedo = str2num(MaskHighFreqV);
            app.umintenzitaEditField.Value = IntSumV;
            app.sumintenzita = str2num(IntSumV);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %zatím nepoužito

        end

        % Button pushed function: NASTAVENButton
        function NASTAVENButtonPushed(app, event)
            %zasílání dat do okna nastavení
            app.nastaveni=nastaveni(app,app.freqVect,app.frekvencemaskovacihotonu,app.intenzitatestovacihotonu,app.sumfrekvenceod,app.sumfrekvencedo,app.sumintenzita,app.mask.Value,app.zvysovani.Value);


        end

        % Button pushed function: NVODButton
        function NVODButtonPushed(app, event)
            %otevření okna návod
            app.navod=navod(app);
        end

        % Value changed function: mask
        function maskValueChanged(app, event)
            value = app.mask.Value;

        end

        % Value changed function: zvysovani
        function zvysovaniValueChanged(app, event)
            value = app.zvysovani.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % delete(app.nastaveni)
            %delete(app.navod)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %% tlačítko pro start a restart měření

            %% stav kdy probíhá měření (možnost měření restartovat)
            if app.startMeasFlag == 0
                app.STARTButton.Text = 'RESTART';
                app.STARTButton.BackgroundColor = 'r';
                app.SlysenoButton.BackgroundColor = [0.47,0.67,0.19];
                app.startMeasFlag = 1;
                app.dontSaveTime = 1;
                SlysenoButtonPushed(app);
                app.dokonceno.Visible = "on";
                app.dokoncenoProcenta = 0;

                %% stav kdy měření neprobíhá (po doměření či restartu) - možnost znovu obnovit měření
            elseif app.startMeasFlag == 1
                %app.buttonPushed =1;
                app.STARTButton.Text = 'START';
                app.dokonceno.Visible = "off";
                app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                app.SlysenoButton.BackgroundColor = 'r';
                app.startMeasFlag = 0;
                app.measCounter = 1;
                app.leftMeas = 1;
                clear sound;


            end


        end

        % Button pushed function: SlysenoButton
        function SlysenoButtonPushed(app, event)
            %% deklarace parametrů pro měření
            Fs = 44100 ;   % nastavení vzorkovacího kmitočtu
            t_max = 60;   %časová délka signálu ale aby v gefu byla tahle hodnota je zapotřebí poděli pi*2(v grafu na x ukáže sice 10 ale)
            t = 0: (2*pi)/Fs : (2*pi*t_max - (2*pi)/Fs); %nastavení časové základny
            f = app.freqVect;%[125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];
            %%%zaklad logaritmu
            LogBase = 20;%20

            P_sampled = importdata('funkce60.mat'); %importování exponenciální funkce
            P_sampled = P_sampled'; % transpozice vektoru
            P_sampled(end) = [];    % zkrácení vektoru aby měl požadovanou délku
            Norm_P_sampled = P_sampled./ max(P_sampled);
            p = importdata('p.mat'); % imortování parametru do polyval k přepočtu dig. úrovně na napětí ze zvukovky a obráceně
            response_f = importdata('new_freq.mat'); % frekvenční osa frekvenční odezvy
            response_R = importdata('new_right.mat'); % pravý kanál frekvenční odezvy
            response_L = importdata('new_left.mat'); % levý kanál frekvenční odezvy
            RefU = 0.185; % referenční napětí (přibližně 1mW)


            radFiltru = 6; % nastavení řádu filtru pro nastavení šířky pásma šumu je potřeba domyslet


            % na začátku měření névé frekvence se přetane přehrávat
            % předchozí ton
            clear sound;

            %% ukládání času reakce na tetovací ton a procenta měření
            if app.dontSaveTime == 0
                %% procenta (nevim jak moc dobře funguje)
                Pridejprocenta = round(100/(2*numel(app.freqVect))); % počet procent pro jedno kolo měření
                app.dokoncenoProcenta = app.dokoncenoProcenta + Pridejprocenta; %přičítání procent na nové kolo
                if app.dokoncenoProcenta > 100 %pojistka aby procenta nepřesáhly 100%
                    app.dokoncenoProcenta = 100;
                end
                app.dokonceno.Text = append(string(app.dokoncenoProcenta)," % dokončeno"); % výpis procent

                %% ukládání času reakce na tetovací ton
                if app.leftMeas == 0
                    app.timeRight(app.measCounter) = toc; % ukládání času reakce na tetovací ton pro pravé ucho
                elseif app.leftMeas == 1
                    if app.measCounter > 1
                        app.timeLeft(app.measCounter-1) = toc; % ukládání času reakce na tetovací ton pro levé ucho
                    end
                end
            else
                app.dontSaveTime =0;
            end


            if app.startMeasFlag == 1
                %% generování maskovacích tónů
                % ozkoušet..............................................................
                if ~isempty(app.frekvencemaskovacihotonu)
                    if numel(app.frekvencemaskovacihotonu) == numel(app.intenzitatestovacihotonu)
                        for i =1:numel(app.frekvencemaskovacihotonu)
                            indexOfFreq  = find(response_f == app.frekvencemaskovacihotonu(i));
                            testTonKarta(i) = 10^((app.intenzitatestovacihotonu(i) - response_R(indexOfFreq)) / LogBase) * RefU;
                            %digitalniUrovenTest(i) = polyval(p, testTonKarta(i));
                            digitalniUrovenTest(i) = ( testTonKarta(i) - p(2)) / p(1);
                            MaskTone =digitalniUrovenTest(i).*sin(app.frekvencemaskovacihotonu(i)*t);
                            sound(MaskTone,Fs);
                        end
                    end
                end
                % %% generování maskovacího šumu (pořešit filtr)
                % if ~isempty(app.sumfrekvenceod)
                %     if (numel(app.sumfrekvenceod) == numel(app.sumfrekvencedo) ) && (numel(app.sumfrekvenceod)== numel(app.sumintenzita) )%nevim proč nefinguje tahle podmínka
                %         for i =1:numel(app.sumfrekvenceod)
                %             % jak pořešit intenzitu v celé pásmu?
                %             delkaPasma = app.sumfrekvencedo(i)-app.sumfrekvenceod(i);
                %             sumWhite = app.sumintenzita(i) * randn(size(t));
                %             [b, a] = butter(radFiltru, [app.sumfrekvenceod(i), app.sumfrekvencedo(i)]/(Fs/2), 'bandpass');
                %             VyfitrovanySum = filter(b, a, sumWhite)  ;
                %             sound(VyfitrovanySum,Fs);
                %         end
                %     end
                % end

                %% generování maskovacího šumu (pořešit filtr)
                if ~isempty(app.sumfrekvenceod)
                    if (numel(app.sumfrekvenceod) == numel(app.sumfrekvencedo) ) && (numel(app.sumfrekvenceod)== numel(app.sumintenzita) )%nevim proč nefinguje tahle podmínka
                        for i =1:numel(app.sumfrekvenceod)
                            noise = randn( 1,Fs);%FS
                            f_c = 1000;
                            NOISE = fft(noise); NOISE = NOISE(1:numel(NOISE)/2);% odfiltrování druhý půlky noisu

                            NOISE(1:app.sumfrekvenceod-1) = 0; NOISE(app.sumfrekvencedo+1:end) = 0; % pro úrčité pásmo frekvence filtrace

                            NOISE = [NOISE flip(NOISE)]; % zrcadlení ve spektru

                            noise = real(ifft(NOISE)); %převod ze spektra na šum
                            noise = repmat(noise,1,10);
                            indexOfFreqOD  = find(response_f == app.sumfrekvenceod); indexOfFreqDO  = find(response_f == app.sumfrekvencedo);
                            averageSPL = sum(response_R(indexOfFreqOD:indexOfFreqDO))/numel(response_R(indexOfFreqOD:indexOfFreqDO));
                            Uzvukovka = RefU*10^((app.sumintenzita-averageSPL)/LogBase);
                            AMPL = ( Uzvukovka - p(2)) / p(1);
                            
                            U1 = AMPL*sin(2*pi*f_c*t); U1(end) = [];
                            std(U1); % Standardní odchylka

                            noise = noise.*(std(U1)/std(noise));
                            %std(noise);%
                            sound(noise,Fs);

                        end
                    end
                end

                %% generování šumu pro maskování netestovaného ucha
                MaskNetest = 0.1 * randn(size(t));
                [b, a] = butter(4, [10, 20000]/(Fs/2), 'bandpass');
                VyfitrovanyMaskNetest= filter(b, a, MaskNetest)  ;
                %sound(VyfitrovanyMaskNetest,Fs);


                %% hlavní část programu: MĚŘENÍ


                if app.measCounter < numel(f)+1 % vyhodnocení jestli má ještě probíhat měření v závislosti na počtu testovacích tónů
                    %% měření levého ucha
                    if app.leftMeas == 1
                        frequence = f(app.measCounter); % výběr frekvence
                        signal = sin(frequence*t); % generování sinusového signálu pro měření

                        if app.mask.Value == 1 % pokud je zaplé maskování netestovaného ucha zapne se zde
                            sound([zeros(size(t)); VyfitrovanyMaskNetest],Fs);
                        end

                        if app.zvysovani.Value == 0
                            signal = signal.*Norm_P_sampled; %generování spojitě zvyšujícího se signálu
                        elseif app.zvysovani.Value == 1
                            indexOfFreq = find(response_f == frequence);
                            stepTime = length(t) / length(app.steps); % časová delka jednoh kroku
                            for i = 1:length(app.steps)
                                %% Schodové zvyšování intenzity přepočet hodnot v SPL v dB na digitální úroveň signálů pro jednotlivé frekvence
                                %napětí ze zvukovky pro jednotlivé kroky zvyšování intenzity
                                stepIntenzity(i) = 10^((app.steps(i) - response_R(indexOfFreq)) / LogBase) * RefU;
                                stepIntenzityDigit(i) = ( stepIntenzity(i) - p(2)) / p(1);
                                % stepIntenzityDigit(i) = polyval(p, stepIntenzity(i)); % digitální velikost kroku
                                index_range = (i-1)*stepTime + 1 : i*stepTime;
                                stepsSignal(index_range) = stepIntenzityDigit(i);
                            end
                            % app.frekvencemaskovachotnuEditField.Value =num2str(stepIntenzityDigit);
                            signal = signal.*stepsSignal; % generace testovacího signálu se schodovitým zvyšováním intenzity
                        end
                        sound([signal;zeros(size(t))],Fs);
                        %sound(signal,Fs);
                        tic
                        app.leftMeas = 0;
                        %% měření pravého ucha
                    elseif app.leftMeas == 0
                        frequence = f(app.measCounter); % výběr frekvence
                        signal = sin(frequence*t); % genarace sinusového signálu

                        if app.mask.Value == 1 % maskování netestovaného ucha (pokud je zvoleno)
                            sound([VyfitrovanyMaskNetest;zeros(size(t))],Fs)
                        end

                        if app.zvysovani.Value == 0
                            signal = signal.*Norm_P_sampled; %generování spojitého zvyšování intenzity test. tónu

                        elseif app.zvysovani.Value == 1
                            indexOfFreq = find(response_f == frequence);
                            stepTime = length(t) / length(app.steps); % časová delka jednoh kroku
                            for i = 1:length(app.steps)
                                %% Schodové zvyšování intenzity
                                %napětí ze zvukovky přepočtena z dB
                                stepIntenzity(i) = 10^((app.steps(i) - response_L(indexOfFreq)) / LogBase) * RefU;
                                % digitální velikost kroku
                                %stepIntenzityDigit(i) = polyval(p, stepIntenzity(i));
                                stepIntenzityDigit(i) = ( stepIntenzity(i) - p(2)) / p(1);
                                index_range = (i-1)*stepTime + 1 : i*stepTime;
                                stepsSignal(index_range) = stepIntenzityDigit(i);
                            end
                            % app.vektortestovacchfrekvencEditField.Value = num2str(stepIntenzityDigit);
                            signal = signal.*stepsSignal;
                        end
                        sound([zeros(size(t)); signal],Fs);
                        tic
                        app.measCounter =  app.measCounter + 1;
                        app.leftMeas =1;
                    end


                else
                    %restarting
                    app.startMeasFlag = 0;
                    app.STARTButton.Text = 'START';
                    app.dokonceno.Visible = "off";
                    app.dokoncenoProcenta = 0;
                    app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                    app.SlysenoButton.BackgroundColor = 'r';

                    if app.zvysovani.Value == 0

                        for k= 1:length(app.timeLeft)
                            timePointLeft(k) = floor((app.timeLeft(k)/t_max)*length(t));
                            ampiltudeLeft(k) = Norm_P_sampled(timePointLeft(k));
                        end
                        for j = 1:length(app.timeRight)
                            timePointRight(j) = floor((app.timeRight(j)/t_max)*length(t));
                            ampiltudeRight(j) = Norm_P_sampled(timePointRight(j));
                        end

                        %% přepočet na dB u spojitého zvyšování intenzity
                        for i = 1:numel(ampiltudeRight)
                            indexFreq = find(response_f == f(i)); % vyhledání frekvence ve vektoru frekvencí frekvenční odezvy
                            rightChanel = response_R(indexFreq); % SPL úroveň frekvenční odezvy pravý kanál
                            leftChanel = response_L(indexFreq);  % SPL úroveň frekvenční odezvy levý kanál
                            vstupniNapetiRigt(i) = polyval(p, ampiltudeRight(i)); % přepočet digitálního napětí na napětí na výstupu zvukovky pravý kanál
                            vstupniNapetiLeft(i) = polyval(p, ampiltudeLeft(i));  % přepočet digitálního napětí na napětí na výstupu zvukovky levý kanál
                            SPLleft(i) = leftChanel + LogBase*log10(vstupniNapetiLeft(i)/RefU);   % SPL na výstupu sluchátek levý kanál
                            SPLRight(i) = rightChanel + LogBase*log10(vstupniNapetiRigt(i)/RefU); % SPL na výstupu sluchátek pravý kanál
                        end


                        %% přepočet na dB u schodovitého zvyšování intenzity
                    elseif app.zvysovani.Value == 1

                        for i = 1:numel(app.timeLeft)
                            pravy= app.timeRight(i)/(t_max/numel(app.steps)); % hledání časového intervalu schodové funkce
                            levy=  app.timeLeft(i)/(t_max/numel(app.steps));  % hledání časového intervalu schodové funkce
                            indexRight = floor(pravy);                        % hledání časového intervalu schodové funkce
                            indexLeft = floor(levy);                          % hledání časového intervalu schodové funkce
                            %app.frekvencemaskovachotnuEditField.Value =num2str(indexLeft);
                            %app.vektortestovacchfrekvencEditField.Value = num2str(indexRight);
                            SPLleft(i) = app.steps(indexRight+1); % výběr jednotlivých rkokův dB pro levé ucho
                            SPLRight(i) = app.steps(indexLeft+1); % výběr jednotlivých rkokův dB pro pravé ucho
                        end

                    end

                    %% vykreslení audiogramu pokud není použito maskování netestovaného ucha
                    if app.mask.Value == 0
                        plot(app.UIAxes,f,SPLRight,'r-o',f,SPLleft,'b-x','MarkerSize',7,'LineWidth',1.5);
                        legend(app.UIAxes, 'Pravé ucho', 'Levé ucho', 'Location','southeast');
                        %% vykreslení audiogramu pokud je použito maskování netestovaného ucha
                    elseif app.mask.Value == 1
                        plot(app.UIAxes,f,SPLRight,'rs-',f,SPLleft,'b-^','MarkerSize',7,'LineWidth',1.5);
                        legend(app.UIAxes, 'Pravé ucho', 'Levé ucho', 'Location','southeast');
                    end
                    %xlim(app.UIAxes, [min(f), max(f)])

                    app.measCounter = 1;
                    clear sound;

                end

            end
        end

        % Value changed function: vektortestovacchfrekvencEditField
        function vektortestovacchfrekvencEditFieldValueChanged(app, event)
            value = app.vektortestovacchfrekvencEditField.Value;

        end

        % Value changed function: frekvencemaskovachotnuEditField
        function frekvencemaskovachotnuEditFieldValueChanged(app, event)
            value = app.frekvencemaskovachotnuEditField.Value;

        end

        % Value changed function: intenzitatestovanhotnuEditField
        function intenzitatestovanhotnuEditFieldValueChanged(app, event)
            value = app.intenzitatestovanhotnuEditField.Value;

        end

        % Value changed function: umfrekvenceodEditField
        function umfrekvenceodEditFieldValueChanged(app, event)
            value = app.umfrekvenceodEditField.Value;

        end

        % Value changed function: umfrekvencedoEditField
        function umfrekvencedoEditFieldValueChanged(app, event)
            value = app.umfrekvencedoEditField.Value;

        end

        % Value changed function: umintenzitaEditField
        function umintenzitaEditFieldValueChanged(app, event)
            value = app.umintenzitaEditField.Value;

        end

        % Button pushed function: ULOZITButton
        function ULOZITButtonPushed(app, event)
            %% zatím jen ukládáme print screen
            screen = getframe(app.UIFigure);
            str = append(app.path,app.FilenameEditField.Value,".png");
            fileName = convertStringsToChars(str);
            % Uložení obrázku do souboru
            if exist(fileName, 'file')
                answer = questdlg('Soubor s tímto názvem již existuje. Chcete ho přepsat?', ...
                    'Uložit obrázek', 'Ano', 'Ne', 'Ne');
                if strcmp(answer, 'Ne')
                    return;
                end

            end
            imwrite(screen.cdata, fileName);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% výběr adresáře pro ukládání screenu
            selectedFolder = uigetdir(app.path);
            app.path = string(selectedFolder);
            app.path =append(app.path,"\");
            app.LabelPath.Text = char(app.path);
            %app.umintenzitaEditField.Value = char(app.path);
        end

        % Button pushed function: AUTOSCALEButton
        function AUTOSCALEButtonPushed(app, event)
            if app.ScaleFlag == 0
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.XTickLabelMode = 'auto';
                app.AUTOSCALEButton.Text = 'ZPĚT';
                app.ScaleFlag = 1;
            elseif app.ScaleFlag == 1

                app.UIAxes.XLim = [0 8000];
                app.UIAxes.YLim = [-10 90];
                app.UIAxes.XTick = [1 1000 2000 3000 4000 5000 6000 7000 8000];
                app.UIAxes.XTickMode = 'manual';
                app.UIAxes.XTickLabelMode = 'manual';
                app.UIAxes.XTickLabel = {'1'; '1000'; '2000'; '3000'; '4000'; '5000'; '6000'; '7000'; '8000'};

                app.AUTOSCALEButton.Text = 'AUTOSCALE';
                app.ScaleFlag = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AUDIOGRAM')
            xlabel(app.UIAxes, 'Frekvence Hz')
            ylabel(app.UIAxes, 'SPL dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8000];
            app.UIAxes.YLim = [-10 90];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.XTick = [1 1000 2000 3000 4000 5000 6000 7000 8000];
            app.UIAxes.XTickLabel = {'1'; '1000'; '2000'; '3000'; '4000'; '5000'; '6000'; '7000'; '8000'};
            app.UIAxes.Tag = 'tagGrafu';
            app.NASTAVENButton = uibutton(app.UIFigure, 'push');
            app.NASTAVENButton.ButtonPushedFcn = createCallbackFcn(app, @NASTAVENButtonPushed, true);
            app.NASTAVENButton.Text = 'NASTAVENÍ';
            app.nastavenhodnotyLabel = uilabel(app.UIFigure);
            app.nastavenhodnotyLabel.Text = 'nastavené hodnoty';
            app.NVODButton = uibutton(app.UIFigure, 'push');
            app.NVODButton.ButtonPushedFcn = createCallbackFcn(app, @NVODButtonPushed, true);
            app.NVODButton.Text = 'NÁVOD';
            app.zvysovaniintenzity1schod0spojEditFieldLabel = uilabel(app.UIFigure);
            app.zvysovaniintenzity1schod0spojEditFieldLabel.Text = 'zvysovani intenzity(1=schod, 0 = spoj)';
            app.zvysovani = uieditfield(app.UIFigure, 'numeric');
            app.zvysovani.ValueChangedFcn = createCallbackFcn(app, @zvysovaniValueChanged, true);
            app.zvysovani.Editable = 'off';
            app.masknetestucha1ANO0NEEditFieldLabel = uilabel(app.UIFigure);
            app.masknetestucha1ANO0NEEditFieldLabel.Text = 'mask netest ucha (1= ANO, 0 = NE)';
            app.mask = uieditfield(app.UIFigure, 'numeric');
            app.mask.ValueChangedFcn = createCallbackFcn(app, @maskValueChanged, true);
            app.mask.Editable = 'off';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SlysenoButton = uibutton(app.UIFigure, 'push');
            app.SlysenoButton.ButtonPushedFcn = createCallbackFcn(app, @SlysenoButtonPushed, true);
            app.SlysenoButton.Text = 'SLYŠENO';
            app.vektortestovacchfrekvencEditFieldLabel = uilabel(app.UIFigure);
            app.vektortestovacchfrekvencEditFieldLabel.Text = 'vektor testovacích frekvencí';
            app.vektortestovacchfrekvencEditField = uieditfield(app.UIFigure, 'text');
            app.vektortestovacchfrekvencEditField.ValueChangedFcn = createCallbackFcn(app, @vektortestovacchfrekvencEditFieldValueChanged, true);
            app.intenzitatestovanhotnuEditFieldLabel = uilabel(app.UIFigure);
            app.intenzitatestovanhotnuEditFieldLabel.Text = 'intenzita testovaného tónu';
            app.intenzitatestovanhotnuEditField = uieditfield(app.UIFigure, 'text');
            app.intenzitatestovanhotnuEditField.ValueChangedFcn = createCallbackFcn(app, @intenzitatestovanhotnuEditFieldValueChanged, true);
            app.intenzitatestovanhotnuEditField.Editable = 'off';
            app.frekvencemaskovachotnuEditFieldLabel = uilabel(app.UIFigure);
            app.frekvencemaskovachotnuEditFieldLabel.Text = 'frekvence maskovacího tónu';
            app.frekvencemaskovachotnuEditField = uieditfield(app.UIFigure, 'text');
            app.frekvencemaskovachotnuEditField.ValueChangedFcn = createCallbackFcn(app, @frekvencemaskovachotnuEditFieldValueChanged, true);
            app.umfrekvenceodEditFieldLabel = uilabel(app.UIFigure);
            app.umfrekvenceodEditFieldLabel.Text = 'šum frekvence  od';
            app.umfrekvenceodEditField = uieditfield(app.UIFigure, 'text');
            app.umfrekvenceodEditField.ValueChangedFcn = createCallbackFcn(app, @umfrekvenceodEditFieldValueChanged, true);
            app.umfrekvenceodEditField.Editable = 'off';
            app.umfrekvencedoEditFieldLabel = uilabel(app.UIFigure);
            app.umfrekvencedoEditFieldLabel.Text = 'šum frekvence  do';
            app.umfrekvencedoEditField = uieditfield(app.UIFigure, 'text');
            app.umfrekvencedoEditField.ValueChangedFcn = createCallbackFcn(app, @umfrekvencedoEditFieldValueChanged, true);
            app.umfrekvencedoEditField.Editable = 'off';
            app.umintenzitaEditFieldLabel = uilabel(app.UIFigure);
            app.umintenzitaEditFieldLabel.Text = 'šum intenzita';
            app.umintenzitaEditField = uieditfield(app.UIFigure, 'text');
            app.umintenzitaEditField.ValueChangedFcn = createCallbackFcn(app, @umintenzitaEditFieldValueChanged, true);
            app.umintenzitaEditField.Editable = 'off';
            app.ULOZITButton = uibutton(app.UIFigure, 'push');
            app.ULOZITButton.ButtonPushedFcn = createCallbackFcn(app, @ULOZITButtonPushed, true);
            app.ULOZITButton.Text = 'ULOŽIT';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'název souboru';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'foldr.png');
            app.Button.Text = '';
            app.dokonceno = uilabel(app.UIFigure);
            app.dokonceno.Visible = 'off';
            app.dokonceno.Text = '0 % dokončeno';
            app.AUTOSCALEButton = uibutton(app.UIFigure, 'push');
            app.AUTOSCALEButton.ButtonPushedFcn = createCallbackFcn(app, @AUTOSCALEButtonPushed, true);
            app.AUTOSCALEButton.Text = 'AUTOSCALE';
            app.LabelPath = uilabel(app.UIFigure);
            app.LabelPath.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hlavniOkno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end