classdef nastaveni < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        testovackmitotyLabel          matlab.ui.control.Label
        SPLumuLabel                   matlab.ui.control.Label
        hornmeznkmitoetpsmaLabel      matlab.ui.control.Label
        dolnmeznkmitoetpsmaLabel      matlab.ui.control.Label
        editLabel_2                   matlab.ui.control.Label
        MaskLowFreqCHB                matlab.ui.control.CheckBox
        MaskLowFreqV                  matlab.ui.control.EditField
        HzLabel_7                     matlab.ui.control.Label
        IntSumV                       matlab.ui.control.EditField
        dBLabel_4                     matlab.ui.control.Label
        MaskHighFreqV                 matlab.ui.control.EditField
        HzLabel_6                     matlab.ui.control.Label
        pridatMaskSum                 matlab.ui.control.Button
        pidatMaskTon                  matlab.ui.control.Button
        pidatTestovaciFrekvenci       matlab.ui.control.Button
        HzLabel_4                     matlab.ui.control.Label
        pidatnovtn2020000HzLabel      matlab.ui.control.Label
        editLabel                     matlab.ui.control.Label
        CheckBoxTFV                   matlab.ui.control.CheckBox
        testFreqVect                  matlab.ui.control.EditField
        OKButton                      matlab.ui.control.Button
        pasmoMaxFreq                  matlab.ui.control.NumericEditField
        doEditFieldLabel              matlab.ui.control.Label
        sumIntenzita                  matlab.ui.control.NumericEditField
        SPL090dBLabel                 matlab.ui.control.Label
        pasmoMinFreq                  matlab.ui.control.NumericEditField
        frekvennpsmo2020000HzodLabel  matlab.ui.control.Label
        dBLabel_2                     matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        PidnmaskovachoumuLabel        matlab.ui.control.Label
        intenzitaMaskTonu             matlab.ui.control.NumericEditField
        intenzitaLabel                matlab.ui.control.Label
        frekvenceMaskTonu             matlab.ui.control.NumericEditField
        frekvencetnu2020000HzLabel    matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        PidnmaskovachotnuLabel        matlab.ui.control.Label
        ZvysovaniIntenzityTonu        matlab.ui.container.ButtonGroup
        schod                         matlab.ui.control.RadioButton
        spojite                       matlab.ui.control.RadioButton
        MaskovaniNetestovanehoUcha    matlab.ui.container.ButtonGroup
        maskNE                        matlab.ui.control.RadioButton
        maskANO                       matlab.ui.control.RadioButton
        HzLabel                       matlab.ui.control.Label
        FrequencyNewSet               matlab.ui.control.NumericEditField
        TestovacfrekvenceHzLabel      matlab.ui.control.Label
        NastavenmenLabel              matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        kmitoettnuLabel               matlab.ui.control.Label
        SPLtnuLabel                   matlab.ui.control.Label
        MaskToneFreqCHB               matlab.ui.control.CheckBox
        editLabel_3                   matlab.ui.control.Label
        MaskToneFreqV                 matlab.ui.control.EditField
        dBLabel                       matlab.ui.control.Label
        HzLabel_5                     matlab.ui.control.Label
        MaskToneIntV                  matlab.ui.control.EditField
        dBLabel_3                     matlab.ui.control.Label
    end


    properties (Access = private)
        hlavniOkno % Description
        MaskovaniNetestovanehoUchaA_N = 0;
        ZvysovaniIntenzityTonuA_N = 0;
        testFreqVector = [];
        maskToneFreqVector = [];
        maskToneIntVector = [];
        MaskLowFreqVector = [];
        MaskHighFreqVector = [];
        maskSumIntVector = [];
    end

    methods (Access = public)
        % app.nastaveni=nastaveni(app,app.freqVect,app.frekvencemaskovacihotonu,app.intenzitatestovacihotonu,app.sumfrekvenceod,app.sumfrekvencedo,app.sumintenzita);
        function  importdata(app,value)
            app.FrequencyNewSet.Value = value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hlavniOkno, freqVectHlavni, maskFreq, maskInt, sumFreqOD, sumFreqDO, sumFreqInt, maskNetestAN, zvysovaniInt)
            app.hlavniOkno = hlavniOkno;
            app.testFreqVector = freqVectHlavni;
            s = [ sprintf('%g, ',app.testFreqVector(1:end-1)) sprintf('%g',app.testFreqVector(end))];
            app.testFreqVect.Value = s ;
            if s(1) == ','
                s(1)= [];
            end
            app.maskToneFreqVector = maskFreq;
            if ~isempty(maskFreq)
                s = [ sprintf('%g, ',app.maskToneFreqVector(1:end-1)) sprintf('%g',app.maskToneFreqVector(end))];
                if s(1) == ','
                    s(1)= [];
                end
                app.MaskToneFreqV.Value = s ;
            end

            app.maskToneIntVector = maskInt;
            if ~isempty(maskInt)
                s = [ sprintf('%g, ',app.maskToneIntVector(1:end-1)) sprintf('%g',app.maskToneIntVector(end))];
                if s(1) == ','
                    s(1)= [];
                end
                app.MaskToneIntV.Value = s ;
            end
            app.MaskLowFreqVector = sumFreqOD;
            if ~isempty(sumFreqOD)
                s = [ sprintf('%g, ',app.MaskLowFreqVector(1:end-1)) sprintf('%g',app.MaskLowFreqVector(end))];
                if s(1) == ','
                    s(1)= [];
                end
                app.MaskLowFreqV.Value = s ;
            end
            app.MaskHighFreqVector = sumFreqDO;
            if ~isempty(sumFreqDO)
                s = [ sprintf('%g, ',app.MaskHighFreqVector(1:end-1)) sprintf('%g',app.MaskHighFreqVector(end))];
                if s(1) == ','
                    s(1)= [];
                end
                app.MaskHighFreqV.Value = s ;
            end
            app.maskSumIntVector = sumFreqInt;
            if ~isempty(sumFreqInt)
                s = [ sprintf('%g, ',app.maskSumIntVector(1:end-1)) sprintf('%g',app.maskSumIntVector(end))];
                if s(1) == ','
                    s(1)= [];
                end
                app.IntSumV.Value = s ;
            end

            switch maskNetestAN
                case 1
                    app.maskNE.Value = false;
                    app.maskANO.Value = true;
                case 0
                    app.maskNE.Value = true;
                    app.maskANO.Value = false;
            end

            switch zvysovaniInt
                case 1
                    app.spojite.Value = false;
                    app.schod.Value = true;
                case 0
                    app.spojite.Value = true;
                    app.schod.Value = false;
            end
            app.MaskovaniNetestovanehoUchaA_N = maskNetestAN;
            app.ZvysovaniIntenzityTonuA_N = zvysovaniInt;

          
        end

        % Selection changed function: MaskovaniNetestovanehoUcha
        function MaskovaniNetestovanehoUchaSelectionChanged(app, event)
            selectedButton = app.MaskovaniNetestovanehoUcha.SelectedObject;

            switch selectedButton.Text
                case 'ANO'
                    app.MaskovaniNetestovanehoUchaA_N= 1;
                case 'NE'
                    app.MaskovaniNetestovanehoUchaA_N = 0;
            end


        end

        % Selection changed function: ZvysovaniIntenzityTonu
        function ZvysovaniIntenzityTonuSelectionChanged(app, event)
            selectedButton = app.ZvysovaniIntenzityTonu.SelectedObject;

            switch selectedButton.Text
                case 'spojité'
                    app.ZvysovaniIntenzityTonuA_N= 0;
                case 'schodovité'
                    app.ZvysovaniIntenzityTonuA_N = 1;
            end

        end

        % Value changed function: FrequencyNewSet
        function FrequencyNewSetValueChanged(app, event)
 
            value = app.FrequencyNewSet.Value;

        end

        % Value changed function: frekvenceMaskTonu
        function frekvenceMaskTonuValueChanged(app, event)
            value = app.frekvenceMaskTonu.Value;

        end

        % Value changed function: intenzitaMaskTonu
        function intenzitaMaskTonuValueChanged(app, event)
            value = app.intenzitaMaskTonu.Value;

        end

        % Value changed function: pasmoMinFreq
        function pasmoMinFreqValueChanged(app, event)
            value = app.pasmoMinFreq.Value;
            %app.MaskLowFreqVector = value;

        end

        % Value changed function: pasmoMaxFreq
        function pasmoMaxFreqValueChanged(app, event)
            value = app.pasmoMaxFreq.Value;


        end

        % Value changed function: sumIntenzita
        function sumIntenzitaValueChanged(app, event)
            value = app.sumIntenzita.Value;


        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %(app,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu,frequencyNew,frekvenceMaskTonu,intenzitaMaskTonu,pasmoMinFreq,pasmoMaxFreq,sumIntenzita)
            %importNastaveni(app.hlavniOkno,app.MaskovaniNetestovanehoUchaA_N , app.ZvysovaniIntenzityTonuA_N,app.FrequencyNewSet.Value,app.frekvenceMaskTonu.Value,app.intenzitaMaskTonu.Value,app.pasmoMinFreq.Value,app.pasmoMaxFreq.Value,app.sumIntenzita.Value,app.testFreqVect.Value)
            importNastaveni(app.hlavniOkno,app.testFreqVect.Value,app.MaskToneFreqV.Value,app.MaskToneIntV.Value,app.MaskLowFreqV.Value,app.MaskHighFreqV.Value,app.IntSumV.Value,app.MaskovaniNetestovanehoUchaA_N , app.ZvysovaniIntenzityTonuA_N)
            delete(app)
        end

        % Value changed function: testFreqVect
        function testFreqVectValueChanged(app, event)
            value = app.testFreqVect.Value;
            app.testFreqVector =  str2num(value);


            % % Filtrace neplatných znaků (pouze čísla a čárky)
            % %valid_characters = '0123456789,';
            % filtered_input = value(isstrprop(value, 'digit') | value == ',');
            %
            % % Nastavení filtrovaného vstupu zpět do textového pole
            % app.testFreqVect.Value = filtered_input;
        end

        % Value changed function: CheckBoxTFV
        function CheckBoxTFVValueChanged(app, event)
            value = app.CheckBoxTFV.Value;

            switch value
                case 1
                    app.testFreqVect.Editable= 1;
                case 0
                    app.testFreqVect.Editable= 0;
            end

        end

        % Value changed function: MaskToneFreqCHB
        function MaskToneFreqCHBValueChanged(app, event)
            value = app.MaskToneFreqCHB.Value;

            switch value
                case 1
                    app.MaskToneFreqV.Editable= 1;
                     app.MaskToneIntV.Editable= 1;
                case 0
                    app.MaskToneFreqV.Editable= 0;
                    app.MaskToneIntV.Editable= 0;
            end

        end

        % Value changed function: MaskToneFreqV
        function MaskToneFreqVValueChanged(app, event)
            value = app.MaskToneFreqV.Value;
            app.maskToneFreqVector = value;
        end

        % Callback function
        function MaskToneIntCHBValueChanged(app, event)
            value = app.MaskToneIntCHB.Value;

            switch value
                case 1
                    app.MaskToneIntV.Editable= 1;
                case 0
                    app.MaskToneIntV.Editable= 0;
            end

        end

        % Value changed function: MaskToneIntV
        function MaskToneIntVValueChanged(app, event)
            value = app.MaskToneIntV.Value;
            app.maskToneIntVector = value;
        end

        % Value changed function: MaskLowFreqCHB
        function MaskLowFreqCHBValueChanged(app, event)
            value = app.MaskLowFreqCHB.Value;

            switch value
                case 1
                    app.MaskLowFreqV.Editable= 1;
                    app.MaskHighFreqV.Editable= 1;
                    app.IntSumV.Editable= 1;
                case 0
                    app.MaskLowFreqV.Editable= 0;
                     app.MaskHighFreqV.Editable= 0;
                     app.IntSumV.Editable= 0;
            end


        end

        % Value changed function: MaskLowFreqV
        function MaskLowFreqVValueChanged(app, event)
            value = app.MaskLowFreqV.Value;
            app.MaskLowFreqVector = value;
        end

        % Callback function
        function MaskHighFreqCHBValueChanged(app, event)
            value = app.MaskHighFreqCHB.Value;

            switch value
                case 1
                    app.MaskHighFreqV.Editable= 1;
                case 0
                    app.MaskHighFreqV.Editable= 0;
            end

        end

        % Value changed function: MaskHighFreqV
        function MaskHighFreqVValueChanged(app, event)
            value = app.MaskHighFreqV.Value;
            app.MaskHighFreqVector = value;
        end

        % Callback function
        function IntSumCHBValueChanged(app, event)
            value = app.IntSumCHB.Value;

            switch value
                case 1
                    app.IntSumV.Editable= 1;
                case 0
                    app.IntSumV.Editable= 0;
            end

        end

        % Value changed function: IntSumV
        function IntSumVValueChanged(app, event)
            value = app.IntSumV.Value;
            app.maskSumIntVector = value;
        end

        % Button pushed function: pidatTestovaciFrekvenci
        function pidatTestovaciFrekvenciButtonPushed(app, event)
            app.testFreqVector(end+1)=app.FrequencyNewSet.Value;
            app.testFreqVector = sort(app.testFreqVector);
            s = [ sprintf('%g, ',app.testFreqVector(1:end-1)) sprintf('%g',app.testFreqVector(end))];
            if s(1) == ','
                s(1)= [];
            end
            app.testFreqVect.Value = s ;

        end

        % Button pushed function: pidatMaskTon
        function pidatMaskTonButtonPushed(app, event)
            app.maskToneFreqVector(end+1)=app.frekvenceMaskTonu.Value;
            s = [ sprintf('%g, ',app.maskToneFreqVector(1:end-1)) sprintf('%g',app.maskToneFreqVector(end))];
            if s(1) == ','
                s(1)= [];
            end
            app.MaskToneFreqV.Value = s ;

            app.maskToneIntVector(end+1)=app.intenzitaMaskTonu.Value;
            s = [ sprintf('%g, ',app.maskToneIntVector(1:end-1)) sprintf('%g',app.maskToneIntVector(end))];
            if s(1) == ','
                s(1)= [];
            end
            app.MaskToneIntV.Value = s ;


        end

        % Button pushed function: pridatMaskSum
        function pridatMaskSumButtonPushed(app, event)
            app.MaskLowFreqVector(end+1)=app.pasmoMinFreq.Value;
            s = [ sprintf('%g, ',app.MaskLowFreqVector(1:end-1)) sprintf('%g',app.MaskLowFreqVector(end))];
            if s(1) == ','
                s(1)= [];
            end
            app.MaskLowFreqV.Value = s ;

            app.MaskHighFreqVector(end+1)=app.pasmoMaxFreq.Value;
            s = [ sprintf('%g, ',app.MaskHighFreqVector(1:end-1)) sprintf('%g',app.MaskHighFreqVector(end))];
            if s(1) == ','
                s(1)= [];
            end
            app.MaskHighFreqV.Value = s ;

            app.maskSumIntVector(end+1)=app.sumIntenzita.Value;
            s = [ sprintf('%g, ',app.maskSumIntVector(1:end-1)) sprintf('%g',app.maskSumIntVector(end))];
            if s(1) == ','
                s(1)= [];
            end
            app.IntSumV.Value = s ;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.dBLabel_3 = uilabel(app.Panel);
            app.dBLabel_3.Text = 'dB';
            app.MaskToneIntV = uieditfield(app.Panel, 'text');
            app.MaskToneIntV.ValueChangedFcn = createCallbackFcn(app, @MaskToneIntVValueChanged, true);
            app.MaskToneIntV.Editable = 'off';
            app.HzLabel_5 = uilabel(app.Panel);
            app.HzLabel_5.Text = 'Hz';
            app.dBLabel = uilabel(app.Panel);
            app.dBLabel.Text = 'dB';
            app.MaskToneFreqV = uieditfield(app.Panel, 'text');
            app.MaskToneFreqV.ValueChangedFcn = createCallbackFcn(app, @MaskToneFreqVValueChanged, true);
            app.MaskToneFreqV.Editable = 'off';
            app.editLabel_3 = uilabel(app.Panel);
            app.editLabel_3.Text = 'edit';
            app.MaskToneFreqCHB = uicheckbox(app.Panel);
            app.MaskToneFreqCHB.ValueChangedFcn = createCallbackFcn(app, @MaskToneFreqCHBValueChanged, true);
            app.MaskToneFreqCHB.Text = '';
            app.SPLtnuLabel = uilabel(app.Panel);
            app.SPLtnuLabel.Text = 'SPL tónu:';
            app.kmitoettnuLabel = uilabel(app.Panel);
            app.kmitoettnuLabel.Text = 'kmitočet tónu:';
            app.NastavenmenLabel = uilabel(app.UIFigure);
            app.NastavenmenLabel.Text = 'Nastavení měření';
            app.TestovacfrekvenceHzLabel = uilabel(app.UIFigure);
            app.TestovacfrekvenceHzLabel.Text = 'Testovací frekvence (Hz):';
            app.FrequencyNewSet = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyNewSet.Limits = [20 20000];
            app.FrequencyNewSet.AllowEmpty = 'on';
            app.FrequencyNewSet.ValueChangedFcn = createCallbackFcn(app, @FrequencyNewSetValueChanged, true);
            app.FrequencyNewSet.Value = [];
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.MaskovaniNetestovanehoUcha = uibuttongroup(app.UIFigure);
            app.MaskovaniNetestovanehoUcha.SelectionChangedFcn = createCallbackFcn(app, @MaskovaniNetestovanehoUchaSelectionChanged, true);
            app.MaskovaniNetestovanehoUcha.Title = 'Maskování netestovaného ucha';
            app.maskANO = uiradiobutton(app.MaskovaniNetestovanehoUcha);
            app.maskANO.Text = 'ANO';
            app.maskNE = uiradiobutton(app.MaskovaniNetestovanehoUcha);
            app.maskNE.Text = 'NE';
            app.maskNE.Value = true;
            app.ZvysovaniIntenzityTonu = uibuttongroup(app.UIFigure);
            app.ZvysovaniIntenzityTonu.SelectionChangedFcn = createCallbackFcn(app, @ZvysovaniIntenzityTonuSelectionChanged, true);
            app.ZvysovaniIntenzityTonu.Title = 'Zvyšování intenzity testovacího tónu';
            app.spojite = uiradiobutton(app.ZvysovaniIntenzityTonu);
            app.spojite.Text = 'spojité';
            app.spojite.Value = true;
            app.schod = uiradiobutton(app.ZvysovaniIntenzityTonu);
            app.schod.Text = 'schodovité';
            app.PidnmaskovachotnuLabel = uilabel(app.UIFigure);
            app.PidnmaskovachotnuLabel.Text = 'Přidání maskovacího tónu:';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.frekvencetnu2020000HzLabel = uilabel(app.UIFigure);
            app.frekvencetnu2020000HzLabel.Text = ' frekvence tónu (20-20 000 Hz)';
            app.frekvenceMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.frekvenceMaskTonu.Limits = [20 20000];
            app.frekvenceMaskTonu.AllowEmpty = 'on';
            app.frekvenceMaskTonu.ValueChangedFcn = createCallbackFcn(app, @frekvenceMaskTonuValueChanged, true);
            app.frekvenceMaskTonu.Value = [];
            app.intenzitaLabel = uilabel(app.UIFigure);
            app.intenzitaLabel.Text = 'SPL (0-90 dB)';
            app.intenzitaMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.intenzitaMaskTonu.Limits = [0 90];
            app.intenzitaMaskTonu.AllowEmpty = 'on';
            app.intenzitaMaskTonu.ValueChangedFcn = createCallbackFcn(app, @intenzitaMaskTonuValueChanged, true);
            app.intenzitaMaskTonu.Value = [];
            app.PidnmaskovachoumuLabel = uilabel(app.UIFigure);
            app.PidnmaskovachoumuLabel.Text = 'Přidání maskovacího šumu:';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.frekvennpsmo2020000HzodLabel = uilabel(app.UIFigure);
            app.frekvennpsmo2020000HzodLabel.Text = 'frekvenční pásmo (20-20 000 Hz) od:';
            app.pasmoMinFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMinFreq.Limits = [20 20000];
            app.pasmoMinFreq.AllowEmpty = 'on';
            app.pasmoMinFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMinFreqValueChanged, true);
            app.pasmoMinFreq.Value = [];
            app.SPL090dBLabel = uilabel(app.UIFigure);
            app.SPL090dBLabel.Text = 'SPL (0-90 dB)';
            app.sumIntenzita = uieditfield(app.UIFigure, 'numeric');
            app.sumIntenzita.Limits = [0 90];
            app.sumIntenzita.AllowEmpty = 'on';
            app.sumIntenzita.ValueChangedFcn = createCallbackFcn(app, @sumIntenzitaValueChanged, true);
            app.sumIntenzita.Value = [];
            app.doEditFieldLabel = uilabel(app.UIFigure);
            app.doEditFieldLabel.Text = 'do:';
            app.pasmoMaxFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMaxFreq.Limits = [20 20000];
            app.pasmoMaxFreq.AllowEmpty = 'on';
            app.pasmoMaxFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMaxFreqValueChanged, true);
            app.pasmoMaxFreq.Value = [];
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.testFreqVect = uieditfield(app.UIFigure, 'text');
            app.testFreqVect.ValueChangedFcn = createCallbackFcn(app, @testFreqVectValueChanged, true);
            app.testFreqVect.Editable = 'off';
            app.CheckBoxTFV = uicheckbox(app.UIFigure);
            app.CheckBoxTFV.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTFVValueChanged, true);
            app.CheckBoxTFV.Text = '';
            app.editLabel = uilabel(app.UIFigure);
            app.editLabel.Text = 'edit';
            app.pidatnovtn2020000HzLabel = uilabel(app.UIFigure);
            app.pidatnovtn2020000HzLabel.Text = 'přidat nový tón(20-20 000 Hz) :';
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = 'Hz';
            app.pidatTestovaciFrekvenci = uibutton(app.UIFigure, 'push');
            app.pidatTestovaciFrekvenci.ButtonPushedFcn = createCallbackFcn(app, @pidatTestovaciFrekvenciButtonPushed, true);
            app.pidatTestovaciFrekvenci.Text = 'přidat';
            app.pidatMaskTon = uibutton(app.UIFigure, 'push');
            app.pidatMaskTon.ButtonPushedFcn = createCallbackFcn(app, @pidatMaskTonButtonPushed, true);
            app.pidatMaskTon.Text = 'přidat';
            app.pridatMaskSum = uibutton(app.UIFigure, 'push');
            app.pridatMaskSum.ButtonPushedFcn = createCallbackFcn(app, @pridatMaskSumButtonPushed, true);
            app.pridatMaskSum.Text = 'přidat';
            app.HzLabel_6 = uilabel(app.UIFigure);
            app.HzLabel_6.Text = 'Hz';
            app.MaskHighFreqV = uieditfield(app.UIFigure, 'text');
            app.MaskHighFreqV.ValueChangedFcn = createCallbackFcn(app, @MaskHighFreqVValueChanged, true);
            app.MaskHighFreqV.Editable = 'off';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = 'dB';
            app.IntSumV = uieditfield(app.UIFigure, 'text');
            app.IntSumV.ValueChangedFcn = createCallbackFcn(app, @IntSumVValueChanged, true);
            app.IntSumV.Editable = 'off';
            app.HzLabel_7 = uilabel(app.UIFigure);
            app.HzLabel_7.Text = 'Hz';
            app.MaskLowFreqV = uieditfield(app.UIFigure, 'text');
            app.MaskLowFreqV.ValueChangedFcn = createCallbackFcn(app, @MaskLowFreqVValueChanged, true);
            app.MaskLowFreqV.Editable = 'off';
            app.MaskLowFreqCHB = uicheckbox(app.UIFigure);
            app.MaskLowFreqCHB.ValueChangedFcn = createCallbackFcn(app, @MaskLowFreqCHBValueChanged, true);
            app.MaskLowFreqCHB.Text = '';
            app.editLabel_2 = uilabel(app.UIFigure);
            app.editLabel_2.Text = 'edit';
            app.dolnmeznkmitoetpsmaLabel = uilabel(app.UIFigure);
            app.dolnmeznkmitoetpsmaLabel.Text = 'dolní mezní kmitočet pásma:';
            app.hornmeznkmitoetpsmaLabel = uilabel(app.UIFigure);
            app.hornmeznkmitoetpsmaLabel.Text = 'horní mezní kmitočet pásma:';
            app.SPLumuLabel = uilabel(app.UIFigure);
            app.SPLumuLabel.Text = 'SPL šumu: ';
            app.testovackmitotyLabel = uilabel(app.UIFigure);
            app.testovackmitotyLabel.Text = 'testovací kmitočty:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nastaveni(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end